classdef OceanOpticsSpectrometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ConnectSpectrometerButton       matlab.ui.control.Button
        StartliveviewButton             matlab.ui.control.Button
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        IntegrationTimesEditField       matlab.ui.control.NumericEditField
        SaveSpectrumButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        SetBGButton                     matlab.ui.control.Button
        ZeroBGButton                    matlab.ui.control.Button
        SetRefButton                    matlab.ui.control.Button
        NoRefButton                     matlab.ui.control.Button
        SaverawspectrumreferenceandbgButton  matlab.ui.control.Button
        Spinner                         matlab.ui.control.Spinner
        AverageovermultiplespectraLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        spectrometerObj % spectrometer object
        channelIndex=0
        spectrometerIndex = 0;
        timeObj
        rawSpectrum
        Wavelength
        BGSpec
        RefSpec
        N=1
        refSet=false
        
        
    end
    
    methods (Access = private)
        
        function [wavelengths, spectralData]=getAverageSpectrum(app)
            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            spectralData=zeros(size(wavelengths));
            numS=app.N;
            for ii=1:1:numS
                spectralData = spectralData+ invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);
            end
            spectralData=spectralData/numS;
        end
        
        function LiveSpectrum(app,~,~)
            rf=app.RefSpec;
            bg=app.BGSpec;
            [wavelengths, spectralData]=app.getAverageSpectrum();
            app.rawSpectrum=spectralData;
            app.Wavelength=wavelengths;
            if app.refSet
                plot(wavelengths, (spectralData-bg)./(rf-bg), 'Parent', app.UIAxes);
            else
                plot(wavelengths, (spectralData-bg), 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeObj = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LiveSpectrum);    % Specify callback function
            xlim(app.UIAxes, [200, 1000])
            app.RefSpec=1;
            app.BGSpec=0;
        end

        % Button pushed function: StartliveviewButton
        function StartliveviewButtonPushed(app, event)
          start(app.timeObj);
        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            intTime = app.IntegrationTimesEditField.Value*1000000;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, intTime);   
        end

        % Button pushed function: ConnectSpectrometerButton
        function ConnectSpectrometerButtonPushed(app, event)
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            connect(app.spectrometerObj);
            intTime = app.IntegrationTimesEditField.Value*1000000;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, intTime);   
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', app.spectrometerIndex, app.channelIndex, 1);
            % Enable correct for electrical dark.
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', app.spectrometerIndex, app.channelIndex, 1);
            spectrometerName = invoke(app.spectrometerObj, 'getName', app.spectrometerIndex);
            app.Label.Text =[spectrometerName, ' Connected'];

        end

        % Callback function
        function SpinnerValueChanged(app, event)
            app.N = app.Spinner.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.timeObj)
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            fmts={'*.mat';'*.csv'};
            [file,path, indx] = uiputfile(fmts);
            if app.refSet
                spectrum=(app.rawSpectrum-app.BGSpec)./(app.RefSpec-app.BGSpec);
            else
                spectrum=(app.rawSpectrum-app.BGSpec);
            end

            wavelengths=app.Wavelength;
            if indx==1
                save(fullfile(path, file), 'wavelengths','spectrum');
            elseif indx==2
                matws=[wavelengths,spectrum];
                writematrix(matws, fullfile(path, file));
            end
            figure(app.UIFigure)
        end

        % Button pushed function: SetBGButton
        function SetBGButtonPushed(app, event)
                app.BGSpec=app.rawSpectrum;
        end

        % Button pushed function: ZeroBGButton
        function ZeroBGButtonPushed(app, event)
            app.BGSpec=zeros(size(app.rawSpectrum));
        end

        % Button pushed function: SetRefButton
        function SetRefButtonPushed(app, event)
                app.RefSpec=app.rawSpectrum;   
                app.refSet=true;
        end

        % Button pushed function: NoRefButton
        function NoRefButtonPushed(app, event)
            app.RefSpec=ones(size(app.rawSpectrum));
            app.refSet=false;
        end

        % Button pushed function: 
        % SaverawspectrumreferenceandbgButton
        function SaverawspectrumreferenceandbgButtonPushed(app, event)
            fmts={'*.mat'};
            [file,path, indx] = uiputfile(fmts);
            wavelengths=app.Wavelength;
            spectrum=app.rawSpectrum;
            ref=app.RefSpec;
            bg=app.BGSpec;
            save(fullfile(path, file), 'wavelengths','spectrum', "ref", "bg");
            figure(app.UIFigure)
        end

        % Value changed function: Spinner
        function SpinnerValueChanged2(app, event)
            app.N = app.Spinner.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectSpectrometerButton = uibutton(app.LeftPanel, 'push');
            app.ConnectSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometerButtonPushed, true);
            app.ConnectSpectrometerButton.Text = 'Connect Spectrometer';
            app.StartliveviewButton = uibutton(app.LeftPanel, 'push');
            app.StartliveviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartliveviewButtonPushed, true);
            app.StartliveviewButton.Text = 'Start live view';
            app.IntegrationTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (s)';
            app.IntegrationTimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.IntegrationTimesEditField.Value = 1;
            app.SaveSpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetBGButton = uibutton(app.LeftPanel, 'push');
            app.SetBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetBGButtonPushed, true);
            app.SetBGButton.Text = 'Set BG';
            app.ZeroBGButton = uibutton(app.LeftPanel, 'push');
            app.ZeroBGButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroBGButtonPushed, true);
            app.ZeroBGButton.Text = 'Zero BG';
            app.SetRefButton = uibutton(app.LeftPanel, 'push');
            app.SetRefButton.ButtonPushedFcn = createCallbackFcn(app, @SetRefButtonPushed, true);
            app.SetRefButton.Text = 'Set Ref';
            app.NoRefButton = uibutton(app.LeftPanel, 'push');
            app.NoRefButton.ButtonPushedFcn = createCallbackFcn(app, @NoRefButtonPushed, true);
            app.NoRefButton.Text = 'No Ref';
            app.SaverawspectrumreferenceandbgButton = uibutton(app.LeftPanel, 'push');
            app.SaverawspectrumreferenceandbgButton.ButtonPushedFcn = createCallbackFcn(app, @SaverawspectrumreferenceandbgButtonPushed, true);
            app.SaverawspectrumreferenceandbgButton.Text = {'Save raw spectrum,'; 'reference, and bg'};
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged2, true);
            app.Spinner.Value = 1;
            app.AverageovermultiplespectraLabel = uilabel(app.LeftPanel);
            app.AverageovermultiplespectraLabel.Text = 'Average over multiple spectra:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (a.u)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanOpticsSpectrometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end